classdef PathLoss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CoordenadasPersonalizadasPanel  matlab.ui.container.Panel
        LonRXEditField                  matlab.ui.control.NumericEditField
        LonRXEditFieldLabel             matlab.ui.control.Label
        LatRXEditField                  matlab.ui.control.NumericEditField
        LatRXEditFieldLabel             matlab.ui.control.Label
        LonTXEditField                  matlab.ui.control.NumericEditField
        LonTXEditFieldLabel             matlab.ui.control.Label
        LatTXEditField                  matlab.ui.control.NumericEditField
        LatTXEditFieldLabel             matlab.ui.control.Label
        ParmetrosPanel                  matlab.ui.container.Panel
        PerdasdeModeloEditField         matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        FrequnciaMHzEditFieldLabel      matlab.ui.control.Label
        GanhodaReceptoraEditField       matlab.ui.control.NumericEditField
        GanhodaReceptoraEditFieldLabel  matlab.ui.control.Label
        GanhodaTransmissoraEditField    matlab.ui.control.NumericEditField
        FrequnciaMHzEditField           matlab.ui.control.NumericEditField
        GanhodaTransmissoraEditFieldLabel  matlab.ui.control.Label
        AlturadaReceptoraEditField      matlab.ui.control.NumericEditField
        AlturadaReceptoraEditFieldLabel  matlab.ui.control.Label
        AlturadaTransmissoraEditField   matlab.ui.control.NumericEditField
        AlturadaTransmissoraEditFieldLabel  matlab.ui.control.Label
        PerdasdeCaminhoEditField        matlab.ui.control.EditField
        PerdasdeCaminhoEditFieldLabel   matlab.ui.control.Label
        DistnciaEntreAntenasEditField   matlab.ui.control.NumericEditField
        DistnciaEntreAntenasEditFieldLabel  matlab.ui.control.Label
        CaminhoPanel                    matlab.ui.container.Panel
        AtualizarButton                 matlab.ui.control.Button
        GumesdeFacaCheckBox             matlab.ui.control.CheckBox
        TraoLoSCheckBox                 matlab.ui.control.CheckBox
        ElevaoEntreAntenasCheckBox      matlab.ui.control.CheckBox
        AnlisesPanel                    matlab.ui.container.Panel
        DifraoGiovaneliButton           matlab.ui.control.Button
        DifraoDeygoutButton             matlab.ui.control.Button
        AbriroutroenlaceparaanliseLabel  matlab.ui.control.Label
        ArquivosSalvosButton            matlab.ui.control.Button
        DifraoEpsteinPetersonButton     matlab.ui.control.Button
        ElevaodoCaminhoButton           matlab.ui.control.Button
        PosiodaAntenaPanel              matlab.ui.container.Panel
        AntenaReceptoraButton           matlab.ui.control.Button
        AntenaTransmissoraButton        matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        MapaMundiTab                    matlab.ui.container.Tab
        FormatodoMapaDropDown           matlab.ui.control.DropDown
        FormatodoMapaDropDownLabel      matlab.ui.control.Label
        CaminhoTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FiltrodeGumesTab                matlab.ui.container.Tab
        FresnelAtivoCheckBox            matlab.ui.control.CheckBox
        limiteangularfield              matlab.ui.control.NumericEditField
        alturahfield                    matlab.ui.control.NumericEditField
        parametrovfield                 matlab.ui.control.NumericEditField
        SalvarFiltroButton              matlab.ui.control.Button
        LimiteangularSlider             matlab.ui.control.Slider
        LimiteangularSliderLabel        matlab.ui.control.Label
        AlturahSlider                   matlab.ui.control.Slider
        AlturahSliderLabel              matlab.ui.control.Label
        ParametrovSlider                matlab.ui.control.Slider
        ParametrovSliderLabel           matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
properties (Access = private)
    
    BancoD
    amostras=512;

    % Coordenadas iniciais
    Lat1=-18.9106626;
    Lat2=-18.9106626;
    Lon1=-48.2632701;
    Lon2=-48.2632701;

    TxAtual
    RxAtual

    % Objetos gráficos
    CarregarMapa
    graf1
    graf2
    graf3

    % Parâmetros do enlace
    freq=575.142857*10^6;
    ganhot=1;
    ganhor=1;

    alturat=10;
    alturar=10;

    % Dados do enlace
    dist
    tx=txsite(Latitude=-18.1,Longitude=-48.1,AntennaHeight=10);
    rx=rxsite(Latitude=-18.9106626,Longitude=-48.2632701,AntennaHeight=10);

    % Dados dos gumes e modelos
    gumes=0;
    pontosmaiores=[];
    pathloss=0;
    indexgumes=[];

    dadoselev
    indexgumesoriginal=[];
    geometriafiltro

end

methods (Access = private)

    %% Atualizar eixo horizontal para distância

    function atualizaeixox(app)

        nticks=6;

        xticks=round(linspace(1,app.amostras,nticks));

        xlabels=linspace(0,app.dist/1000,nticks);

        app.UIAxes.XLim=[1 app.amostras];

        app.UIAxes.XTick=xticks;

        app.UIAxes.XTickLabel=string(round(xlabels,2));

        xlabel(app.UIAxes,'Distância (km)','FontSize',18)

        ylabel(app.UIAxes,'Elevação (m)','FontSize',18)

        app.UIAxes.FontSize=16;

        app.UIAxes.LineWidth=1.5;

        drawnow;

    end

    %% Atualizar gumes após aplicação dos filtros

    function atualizagumesfiltro(app)
    
        %% Encontrar gumes originais
    
        [indexoriginal,~]=indexgumess(app.dadoselev,app.alturat,app.alturar,app.freq,app.FresnelAtivoCheckBox.Value);
    
        %% Salvar gumes originais
    
        app.indexgumesoriginal=indexoriginal;
    
        %% Preparar geometria dos gumes
    
        app.geometriafiltro=preparageometria(app.indexgumesoriginal,app.dadoselev,app.alturat,app.alturar,app.freq);
    
        %% Obter valores atuais dos filtros
    
        limiteh=app.AlturahSlider.Value;
    
        limitev=app.ParametrovSlider.Value;
    
        limiteangulo=app.LimiteangularSlider.Value;
    
        limitedistancia=Inf;
    
        %% Aplicar filtro
    
        app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);
    
        app.gumes=length(app.indexgumes);
    
        %% Atualizar desenho
    
        desenhagumesfiltro(app);
    
    end

    %% Desenhar gumes filtrados

    function desenhagumesfiltro(app)

        if isempty(app.dadoselev)==true
            return
        end

        n=size(app.dadoselev,2);

        elevmin=min(app.dadoselev(3,:));

        alturatopo=app.dadoselev(3,1)+app.alturat;

        alturaropo=app.dadoselev(3,end)+app.alturar;

        elevmax=max([app.dadoselev(3,:) alturatopo alturaropo]);

        margem=max((elevmax-elevmin)*0.03,1);

        %% Limpar gráfico

        cla(app.UIAxes_2)

        grid(app.UIAxes_2,"on")

        hold(app.UIAxes_2,"on")

        %% Desenhar relevo

        plot(app.UIAxes_2,app.dadoselev(3,:),'LineWidth',2.5);

        %% Desenhar antena transmissora

        line(app.UIAxes_2,[1 1],[app.dadoselev(3,1) alturatopo],'Color',[0.85 0.1 0.1],'LineWidth',3);

        plot(app.UIAxes_2,1,alturatopo,'^','MarkerSize',12,'MarkerFaceColor',[0.85 0.1 0.1],'MarkerEdgeColor','k','LineWidth',1.5);

        %% Desenhar antena receptora

        line(app.UIAxes_2,[n n],[app.dadoselev(3,end) alturaropo],'Color',[0.1 0.3 0.85],'LineWidth',3);

        plot(app.UIAxes_2,n,alturaropo,'^','MarkerSize',12,'MarkerFaceColor',[0.1 0.3 0.85],'MarkerEdgeColor','k','LineWidth',1.5);

        %% Mostrar alturas das antenas

        text(app.UIAxes_2,1.5,alturatopo,"Tx = "+string(app.alturat)+" m",'FontSize',13,'VerticalAlignment','bottom');

        text(app.UIAxes_2,n-0.5,alturaropo,"Rx = "+string(app.alturar)+" m",'FontSize',13,'HorizontalAlignment','right','VerticalAlignment','bottom');

        %% Desenhar gumes

        if isempty(app.indexgumes)==false

            for i=1:length(app.indexgumes)

                indice=app.indexgumes(i);

                alturagume=app.dadoselev(3,indice);

                line(app.UIAxes_2,[indice indice],[elevmin alturagume],'Color',[0.85 0.2 0.1],'LineStyle','--','LineWidth',2);

            end

            %% Destacar gumes

            plot(app.UIAxes_2,app.indexgumes,app.dadoselev(3,app.indexgumes),'o','MarkerSize',11,'MarkerFaceColor',[1 0.5 0],'MarkerEdgeColor',[0.85 0.2 0.1],'LineWidth',2);

        end

        %% Eixo horizontal em distância

        nticks=6;

        xticks2=round(linspace(1,n,nticks));

        xlabels2=linspace(0,app.dist/1000,nticks);

        app.UIAxes_2.XLim=[0.5 n+0.5];

        app.UIAxes_2.XTick=xticks2;

        app.UIAxes_2.XTickLabel=string(round(xlabels2,2));

        %% Formatação

        xlabel(app.UIAxes_2,'Distância (km)','FontSize',18)

        ylabel(app.UIAxes_2,'Elevação (m)','FontSize',18)

        title(app.UIAxes_2,"Gumes Identificados",'FontSize',20)

        app.UIAxes_2.FontSize=16;

        app.UIAxes_2.LineWidth=1.5;

        ylim(app.UIAxes_2,[elevmin-margem elevmax+margem*2]);

        drawnow;

    end

end

methods (Access = public)
    
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Mapa1(app)

            %% Definir caminhos do App
    
            pastabase=fileparts(mfilename('fullpath'));
    
            addpath(genpath(fullfile(pastabase,'FunçõesApp')));
    
            %% Definir valores iniciais do enlace
    
            app.alturat=10;
    
            app.alturar=10;
    
            app.freq=575.142857*10^6;
    
            app.AlturadaTransmissoraEditField.Value=app.alturat;
    
            app.AlturadaReceptoraEditField.Value=app.alturar;
    
            app.FrequnciaMHzEditField.Value=app.freq/10^6;
    
            %% Definir valores iniciais do filtro
    
            app.AlturahSlider.Value=0.375;
    
            app.ParametrovSlider.Value=0.10;
    
            app.LimiteangularSlider.Value=0.40;
    
            app.alturahfield.Value=0.375;
    
            app.parametrovfield.Value=0.10;
    
            app.limiteangularfield.Value=0.40;
    
            %% Iniciar verificação de Fresnel desligada
    
            app.FresnelAtivoCheckBox.Value=false;
    
            %% Inicializar mapa
    
            app.CarregarMapa=geoaxes(app.MapaMundiTab,NextPlot="add",Basemap='streets-light');
    
            title(app.CarregarMapa,"Selecione os Locais das Antenas");
    
            %% Atualizar distância inicial
    
            app.dist=distance(app.tx,app.rx);
    
            app.DistnciaEntreAntenasEditField.Value=app.dist;
    
            %% Definir banco de dados inicial
    
            app.BancoD=fullfile(pastabase,'DadosSalvos','DadosElev');
    
            %% Bloquear campos somente para exibição
    
            app.DistnciaEntreAntenasEditField.Editable='off';
    
            app.PerdasdeModeloEditField.Editable='off';
    
            app.PerdasdeCaminhoEditField.Editable='off';
    
            %% Carregar perfil inicial
    
            load(app.BancoD);
    
            app.amostras=size(dadoselev,2);
    
            %% Aplicar correção do raio efetivo
    
            dadoselev=raioefetivo(dadoselev);
    
            app.dadoselev=dadoselev;
    
            %% Desenhar perfil inicial
    
            cla(app.UIAxes)
    
            grid(app.UIAxes,"on")
    
            hold(app.UIAxes,"on")
    
            plot(app.UIAxes,app.dadoselev(3,:),'LineWidth',2.5);
    
            atualizaeixox(app);
    
            title(app.UIAxes,"Elevação Entre as Antenas",'FontSize',20);
    
            ylim(app.UIAxes,[min(app.dadoselev(3,:)),max(app.dadoselev(3,:))]);
    
            %% Atualizar gráfico dos gumes
    
            atualizagumesfiltro(app);
        end

        % Button pushed function: AntenaTransmissoraButton
        function Transmissora(app, event)
            mouse=drawpoint(app.CarregarMapa);
        
            PosTra=get(mouse,'Position');
        
            delete(mouse);
        
            drawnow;
        
            app.Lat1=PosTra(1);
        
            app.Lon1=PosTra(2);
        
            %% Apagar transmissora anterior
        
            if isempty(app.TxAtual)==false && isvalid(app.TxAtual)==true
        
                delete(app.TxAtual);
        
            end
        
            %% Definir caminho do ícone
        
            pastaapp=fileparts(mfilename('fullpath'));
        
            caminhowifired=fullfile(pastaapp,'Imagens','wifi128.png');
        
            %% Desenhar transmissora atual
        
            app.TxAtual=geoiconchart(app.CarregarMapa,app.Lat1,app.Lon1,caminhowifired,SizeData=30,IconAnchorPoint="center");
        
            msgbox("Coordenadas: "+app.Lat1+" "+app.Lon1,"Transmissão Escolhida!");
        
            %% Atualizar transmissora
        
            app.tx=txsite(Latitude=app.Lat1,Longitude=app.Lon1,AntennaHeight=app.alturat);
        
            %% Atualizar distância
        
            app.dist=distance(app.tx,app.rx);
        
            app.DistnciaEntreAntenasEditField.Value=app.dist;
        end

        % Button pushed function: AntenaReceptoraButton
        function AntenaReceptoraButtonPushed(app, event)
            %% Selecionar posição da receptora no mapa
        
            mouse=drawpoint(app.CarregarMapa);
        
            PosRec=get(mouse,'Position');
        
            delete(mouse);
        
            drawnow;
        
            app.Lat2=PosRec(1);
        
            app.Lon2=PosRec(2);
        
            %% Apagar receptora anterior
        
            if isempty(app.RxAtual)==false && isvalid(app.RxAtual)==true
        
                delete(app.RxAtual);
        
            end
        
            %% Definir caminho do ícone
        
            pastaapp=fileparts(mfilename('fullpath'));
        
            caminhocellred=fullfile(pastaapp,'Imagens','cell128.png');
        
            %% Desenhar receptora atual
        
            app.RxAtual=geoiconchart(app.CarregarMapa,app.Lat2,app.Lon2,caminhocellred,SizeData=30,IconAnchorPoint="center");
        
            msgbox("Coordenadas: "+app.Lat2+" "+app.Lon2,"Recepção escolhida!");
        
            %% Atualizar receptora
        
            app.rx=rxsite(Latitude=app.Lat2,Longitude=app.Lon2,AntennaHeight=app.alturar);
        
            %% Atualizar distância
        
            app.dist=distance(app.tx,app.rx);
        
            app.DistnciaEntreAntenasEditField.Value=app.dist;
        end

        % Button pushed function: ElevaodoCaminhoButton
        function ElevaodoCaminhoButtonPushed(app, event)
            %% Definir caminhos
        
            pastabase=fileparts(mfilename('fullpath'));
        
            app.BancoD=fullfile(pastabase,'DadosSalvos','DadosElev');
        
            %% Obter chave da Google Elevation API
        
            API_KEY=getenv('GOOGLE_ELEVATION_API_KEY');
        
            if isempty(API_KEY)==true
                error('Defina a variável de ambiente GOOGLE_ELEVATION_API_KEY.')
            end
        
            %% Obter perfil de elevação
        
            dadoselev=dadoselevgoogle(app.Lat1,app.Lon1,app.Lat2,app.Lon2,app.amostras,API_KEY);
        
            %% Salvar perfil original
        
            save(app.BancoD,'dadoselev');
        
            %% Atualizar dados do App
        
            app.amostras=size(dadoselev,2);
        
            app.Lat1=dadoselev(1,1);
        
            app.Lon1=dadoselev(2,1);
        
            app.Lat2=dadoselev(1,end);
        
            app.Lon2=dadoselev(2,end);
        
            %% Atualizar transmissora e receptora
        
            app.tx=txsite(Latitude=app.Lat1,Longitude=app.Lon1,AntennaHeight=app.alturat);
        
            app.rx=rxsite(Latitude=app.Lat2,Longitude=app.Lon2,AntennaHeight=app.alturar);
        
            %% Atualizar distância
        
            app.dist=distance(app.tx,app.rx);
        
            app.DistnciaEntreAntenasEditField.Value=app.dist;
        
            %% Aplicar correção do raio efetivo
        
            dadoselev=raioefetivo(dadoselev);
        
            app.dadoselev=dadoselev;
        
            %% Atualizar filtro de gumes
        
            atualizagumesfiltro(app);
        
            %% Desenhar perfil
        
            cla(app.UIAxes)
        
            grid(app.UIAxes,"on")
        
            hold(app.UIAxes,"on")
        
            plot(app.UIAxes,app.dadoselev(3,:),'LineWidth',2.5);
        
            atualizaeixox(app);
        
            title(app.UIAxes,"Elevação Entre as Antenas",'FontSize',20);
        
            ylim(app.UIAxes,[min(app.dadoselev(3,:)),max(app.dadoselev(3,:))]);
        
            %% Abrir aba Caminho
        
            app.TabGroup.SelectedTab=app.CaminhoTab;
        end

        % Button down function: CaminhoTab
        function CaminhoTabButtonDown(app, event)

        end

        % Value changed function: AlturadaTransmissoraEditField
        function AlturadaTransmissoraEditFieldValueChanged(app, event)
            %% Atualizar altura da transmissora
        
            app.alturat=app.AlturadaTransmissoraEditField.Value;
        
            %% Atualizar transmissora
        
            app.tx=txsite(Latitude=app.Lat1,Longitude=app.Lon1,AntennaHeight=app.alturat);
        
            %% Atualizar distância
        
            app.dist=distance(app.tx,app.rx);
        
            app.DistnciaEntreAntenasEditField.Value=app.dist;
        
            %% Atualizar gumes
        
            atualizagumesfiltro(app);
        end

        % Value changed function: AlturadaReceptoraEditField
        function AlturadaReceptoraEditFieldValueChanged(app, event)
            %% Atualizar altura da receptora
        
            app.alturar=app.AlturadaReceptoraEditField.Value;
        
            %% Atualizar receptora
        
            app.rx=rxsite(Latitude=app.Lat2,Longitude=app.Lon2,AntennaHeight=app.alturar);
        
            %% Atualizar distância
        
            app.dist=distance(app.tx,app.rx);
        
            app.DistnciaEntreAntenasEditField.Value=app.dist;
        
            %% Atualizar gumes
        
            atualizagumesfiltro(app);
        end

        % Value changed function: ElevaoEntreAntenasCheckBox
        function ElevaoEntreAntenasCheckBoxValueChanged(app, event)
            %% Obter estado da opção
        
            alturantenas=app.ElevaoEntreAntenasCheckBox.Value;
        
            %% Adicionar alturas das antenas ao perfil
        
            dadoselev1=app.dadoselev;
        
            dadoselev1(3,1)=dadoselev1(3,1)+app.alturat;
        
            dadoselev1(3,end)=dadoselev1(3,end)+app.alturar;
        
            %% Mostrar ou ocultar perfil com as alturas das antenas
        
            if alturantenas==true
        
                app.graf1=plot(app.UIAxes,dadoselev1(3,:),'Visible','on');
        
                atualizaeixox(app);
        
            else
        
                if isempty(app.graf1)==false && isvalid(app.graf1)==true
        
                    set(app.graf1,'Visible','off');
        
                end
        
            end
        
            %% Ajustar limites do eixo
        
            ylim(app.UIAxes,[min(dadoselev1(3,:)),max(dadoselev1(3,:))]);
        end

        % Value changed function: DistnciaEntreAntenasEditField
        function DistnciaEntreAntenasEditFieldValueChanged(app, event)
            
        end

        % Value changed function: TraoLoSCheckBox
        function TraoLoSCheckBoxValueChanged(app, event)
            %% Obter estado da opção
        
            los=app.TraoLoSCheckBox.Value;
        
            %% Preparar perfil com as alturas das antenas
        
            dadoselev=app.dadoselev;
        
            dadoselev(3,1)=dadoselev(3,1)+app.alturat;
        
            dadoselev(3,end)=dadoselev(3,end)+app.alturar;
        
            amostras=size(dadoselev,2);
        
            %% Construir linha de visada
        
            retacorte=linspace(dadoselev(3,1),dadoselev(3,end),amostras);
        
            %% Mostrar ou ocultar LoS
        
            if los==true
        
                app.graf2=plot(app.UIAxes,retacorte,'Visible','on');
        
                atualizaeixox(app);
        
            else
        
                if isempty(app.graf2)==false && isvalid(app.graf2)==true
        
                    set(app.graf2,'Visible','off');
        
                end
        
            end
        
            %% Ajustar limites do eixo
        
            ylim(app.UIAxes,[min(dadoselev(3,:)),max(dadoselev(3,:))]);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: GumesdeFacaCheckBox
        function GumesdeFacaCheckBoxValueChanged(app, event)
            %% Obter estado da opção
        
            gumess=app.GumesdeFacaCheckBox.Value;
        
            %% Preparar perfil com as alturas das antenas
        
            dadoselevplot=app.dadoselev;
        
            dadoselevplot(3,1)=dadoselevplot(3,1)+app.alturat;
        
            dadoselevplot(3,end)=dadoselevplot(3,end)+app.alturar;
        
            %% Mostrar ou ocultar gumes
        
            if gumess==true
        
                app.graf3=plot(app.UIAxes,app.indexgumes,dadoselevplot(3,app.indexgumes),'ro','MarkerFaceColor','r','LineStyle','none','Visible','on');
        
                atualizaeixox(app);
        
            else
        
                if isempty(app.graf3)==false && isvalid(app.graf3)==true
        
                    set(app.graf3,'Visible','off');
        
                end
        
            end
        
            %% Ajustar limites do eixo
        
            ylim(app.UIAxes,[min(dadoselevplot(3,:)),max(dadoselevplot(3,:))]);
        end

        % Value changed function: FrequnciaMHzEditField
        function FrequnciaMHzEditFieldValueChanged(app, event)
            %% Atualizar frequência
        
            app.freq=app.FrequnciaMHzEditField.Value*10^6;
        
            %% Atualizar gumes
        
            atualizagumesfiltro(app);
        end

        % Value changed function: GanhodaTransmissoraEditField
        function GanhodaTransmissoraEditFieldValueChanged(app, event)
            %% Atualizar ganho da transmissora
        
            app.ganhot=app.GanhodaTransmissoraEditField.Value;
        end

        % Value changed function: GanhodaReceptoraEditField
        function GanhodaReceptoraEditFieldValueChanged(app, event)
            %% Atualizar ganho da receptora
        
            app.ganhor=app.GanhodaReceptoraEditField.Value;
        end

        % Button pushed function: DifraoEpsteinPetersonButton
        function DifraoEpsteinPetersonButtonPushed(app, event)
            %% Obter relevo atual
        
            dadoselev=app.dadoselev;
        
            %% Limpar desenho anterior
        
            legend(app.UIAxes,'off');
        
            cla(app.UIAxes,'reset');
        
            hold(app.UIAxes,"on");
        
            grid(app.UIAxes,"on");
        
            %% Calcular perda por difração
        
            pathlossepstein=perdaepstein(app.indexgumes,dadoselev,app.freq,app.gumes,app.alturar,app.alturat);
        
            %% Calcular distância total
        
            dtotal=txrx(1,size(dadoselev,2),app.alturat,app.alturar,dadoselev);
        
            %% Calcular perda em espaço livre
        
            lambda=(3*10^8)/app.freq;
        
            perdalivre=-10*log10((app.ganhot*app.ganhor*(lambda^2))/(((4*pi)^2)*(dtotal^2)));
        
            %% Calcular perda total de caminho
        
            app.pathloss=perdalivre-pathlossepstein;
        
            %% Exibir perda do modelo
        
            app.PerdasdeModeloEditField.Value=string(-pathlossepstein)+" dB";
        
            %% Desenhar Epstein-Peterson
        
            desenhaepsteinapp(app.UIAxes,dadoselev,app.indexgumes,app.alturat,app.alturar);
        
            atualizaeixox(app);
        
            %% Exibir resultados
        
            app.PerdasdeCaminhoEditField.Value=string(app.pathloss)+" dB";
        
            app.DistnciaEntreAntenasEditField.Value=dtotal;
        
            drawnow
        end

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
            %% Limpar gráfico atual
        
            legend(app.UIAxes,'off');
        
            cla(app.UIAxes);
        
            app.graf1=[];
            app.graf2=[];
            app.graf3=[];
        
            %% Desativar elementos auxiliares
        
            app.TraoLoSCheckBox.Value=false;
        
            app.GumesdeFacaCheckBox.Value=false;
        
            app.ElevaoEntreAntenasCheckBox.Value=false;
        
            %% Carregar perfil original
        
            load(app.BancoD);
        
            %% Aplicar correção do raio efetivo
        
            dadoselev=raioefetivo(dadoselev);
        
            app.dadoselev=dadoselev;
        
            %% Atualizar filtro de gumes
        
            atualizagumesfiltro(app);
        
            %% Atualizar gráfico principal
        
            hold(app.UIAxes,"on");
        
            grid(app.UIAxes,"on");
        
            plot(app.UIAxes,app.dadoselev(3,:),'LineWidth',2.5);
        
            atualizaeixox(app);
        
            title(app.UIAxes,"Elevação Entre as Antenas",'FontSize',20);
        
            ylim(app.UIAxes,[min(app.dadoselev(3,:)),max(app.dadoselev(3,:))]);
        
            drawnow
        end

        % Button pushed function: ArquivosSalvosButton
        function ArquivosSalvosButtonPushed(app, event)
            %% Selecionar arquivo salvo
        
            [nome,caminho]=uigetfile('*.mat');
        
            if nome==0
                return
            end
        
            app.BancoD=fullfile(caminho,nome);
        
            %% Carregar perfil
        
            load(app.BancoD);
        
            app.amostras=size(dadoselev,2);
        
            %% Atualizar coordenadas
        
            app.Lat1=dadoselev(1,1);
        
            app.Lon1=dadoselev(2,1);
        
            app.Lat2=dadoselev(1,end);
        
            app.Lon2=dadoselev(2,end);
        
            %% Atualizar transmissora e receptora
        
            app.tx=txsite(Latitude=app.Lat1,Longitude=app.Lon1,AntennaHeight=app.alturat);
        
            app.rx=rxsite(Latitude=app.Lat2,Longitude=app.Lon2,AntennaHeight=app.alturar);
        
            %% Atualizar distância
        
            app.dist=distance(app.tx,app.rx);
        
            app.DistnciaEntreAntenasEditField.Value=app.dist;
        
            %% Atualizar campos de coordenadas
        
            app.LatTXEditField.Value=app.Lat1;
        
            app.LonTXEditField.Value=app.Lon1;
        
            app.LatRXEditField.Value=app.Lat2;
        
            app.LonRXEditField.Value=app.Lon2;
        
            %% Desativar elementos auxiliares
        
            app.TraoLoSCheckBox.Value=false;
        
            app.GumesdeFacaCheckBox.Value=false;
        
            app.ElevaoEntreAntenasCheckBox.Value=false;
        
            %% Aplicar correção do raio efetivo
        
            dadoselev=raioefetivo(dadoselev);
        
            app.dadoselev=dadoselev;
        
            %% Desenhar perfil
        
            cla(app.UIAxes)
        
            hold(app.UIAxes,"on")
        
            grid(app.UIAxes,"on")
        
            plot(app.UIAxes,app.dadoselev(3,:),'LineWidth',2.5)
        
            atualizaeixox(app);
        
            title(app.UIAxes,"Elevação Entre as Antenas",'FontSize',20)
        
            ylim(app.UIAxes,[min(app.dadoselev(3,:)),max(app.dadoselev(3,:))]);
        
            %% Atualizar filtro de gumes
        
            atualizagumesfiltro(app);
        end

        % Value changed function: LatTXEditField
        function LatTXEditFieldValueChanged(app, event)
            app.Lat1 = app.LatTXEditField.Value;   
        end

        % Value changed function: LonTXEditField
        function LonTXEditFieldValueChanged(app, event)
            app.Lon1 = app.LonTXEditField.Value;     
        end

        % Value changed function: LatRXEditField
        function LatRXEditFieldValueChanged(app, event)
            app.Lat2 = app.LatRXEditField.Value;          
        end

        % Value changed function: LonRXEditField
        function LonRXEditFieldValueChanged(app, event)
            app.Lon2 = app.LonRXEditField.Value; 
        end

        % Value changed function: FormatodoMapaDropDown
        function FormatodoMapaDropDownValueChanged(app, event)
            %% Atualizar formato do mapa
        
            tipomapa=app.FormatodoMapaDropDown.Value;
        
            app.CarregarMapa.Basemap=tipomapa;
        end

        % Clicked callback: FormatodoMapaDropDown
        function FormatodoMapaDropDownClicked(app, event)

        end

        % Button pushed function: DifraoDeygoutButton
        function DifraoDeygoutButtonPushed(app, event)
            %% Obter relevo atual
        
            dadoselev=app.dadoselev;
        
            %% Limpar desenho anterior
        
            legend(app.UIAxes,'off');
        
            cla(app.UIAxes,'reset');
        
            hold(app.UIAxes,"on");
        
            grid(app.UIAxes,"on");
        
            %% Calcular perda por difração
        
            pathlossdeygout=perdadeygout(app.indexgumes,dadoselev,app.freq,app.gumes,app.alturar,app.alturat);
        
            %% Calcular distância total
        
            dtotal=txrx(1,size(dadoselev,2),app.alturat,app.alturar,dadoselev);
        
            %% Calcular perda em espaço livre
        
            lambda=(3*10^8)/app.freq;
        
            perdalivre=-10*log10((app.ganhot*app.ganhor*(lambda^2))/(((4*pi)^2)*(dtotal^2)));
        
            %% Calcular perda total de caminho
        
            app.pathloss=perdalivre-pathlossdeygout;
        
            %% Exibir perda do modelo
        
            app.PerdasdeModeloEditField.Value=string(-pathlossdeygout)+" dB";
        
            %% Desenhar Deygout
        
            desenhadeygoutapp(app.UIAxes,dadoselev,app.indexgumes,app.alturat,app.alturar,app.freq);
        
            atualizaeixox(app);
        
            %% Exibir resultados
        
            app.PerdasdeCaminhoEditField.Value=string(app.pathloss)+" dB";
        
            app.DistnciaEntreAntenasEditField.Value=dtotal;
        
            drawnow
        end

        % Button pushed function: DifraoGiovaneliButton
        function DifraoGiovaneliButtonPushed(app, event)
            %% Obter relevo atual
        
            dadoselev=app.dadoselev;
        
            %% Limpar desenho anterior
        
            legend(app.UIAxes,'off');
        
            cla(app.UIAxes,'reset');
        
            hold(app.UIAxes,"on");
        
            grid(app.UIAxes,"on");
        
            %% Calcular perda por difração
        
            pathlossgio=perdagiovaneli(app.indexgumes,dadoselev,app.freq,app.gumes,app.alturar,app.alturat);
        
            %% Calcular distância total
        
            dtotal=txrx(1,size(dadoselev,2),app.alturat,app.alturar,dadoselev);
        
            %% Calcular perda em espaço livre
        
            lambda=(3*10^8)/app.freq;
        
            perdalivre=-10*log10((app.ganhot*app.ganhor*(lambda^2))/(((4*pi)^2)*(dtotal^2)));
        
            %% Calcular perda total de caminho
        
            app.pathloss=perdalivre-pathlossgio;
        
            %% Exibir perda do modelo
        
            app.PerdasdeModeloEditField.Value=string(-pathlossgio)+" dB";
        
            %% Desenhar Giovaneli
        
            desenhagiovaneliapp(app.UIAxes,dadoselev,app.indexgumes,app.alturat,app.alturar,app.freq);
        
            atualizaeixox(app);
        
            %% Exibir resultados
        
            app.PerdasdeCaminhoEditField.Value=string(app.pathloss)+" dB";
        
            app.DistnciaEntreAntenasEditField.Value=dtotal;
        
            drawnow

        end

        % Button pushed function: SalvarFiltroButton
        function SalvarFiltroButtonPushed(app, event)
            %% Valores atuais dos filtros

            limiteh=app.AlturahSlider.Value;

            limitev=app.ParametrovSlider.Value;

            limiteangulo=app.LimiteangularSlider.Value;

            limitedistancia=Inf;

            %% Salvar conjunto de gumes filtrados

            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);

            app.gumes=length(app.indexgumes);

            %% Atualizar gráfico

            desenhagumesfiltro(app);
        end

        % Value changing function: ParametrovSlider
        function ParametrovSliderValueChanging(app, event)
            limitev=event.Value;

            %% Atualizar campo numérico

            app.parametrovfield.Value=limitev;

            %% Valores atuais dos outros filtros

            limiteh=app.AlturahSlider.Value;

            limiteangulo=app.LimiteangularSlider.Value;

            limitedistancia=Inf;

            %% Aplicar filtro

            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);

            app.gumes=length(app.indexgumes);

            %% Atualizar gráfico

            desenhagumesfiltro(app);
        end

        % Value changed function: ParametrovSlider
        function ParametrovSliderValueChanged(app, event)
            limitev=app.ParametrovSlider.Value;
        
            %% Atualizar campo numérico
        
            app.parametrovfield.Value=limitev;
        
            %% Valores atuais dos outros filtros
        
            limiteh=app.AlturahSlider.Value;
        
            limiteangulo=app.LimiteangularSlider.Value;
        
            limitedistancia=Inf;
        
            %% Aplicar filtro
        
            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);
        
            app.gumes=length(app.indexgumes);
        
            %% Atualizar gráfico
        
            desenhagumesfiltro(app);
        end

        % Value changed function: parametrovfield
        function parametrovfieldValueChanged(app, event)
            limitev=app.parametrovfield.Value;
        
            %% Atualizar slider
        
            app.ParametrovSlider.Value=limitev;
        
            %% Valores atuais dos outros filtros
        
            limiteh=app.AlturahSlider.Value;
        
            limiteangulo=app.LimiteangularSlider.Value;
        
            limitedistancia=Inf;
        
            %% Aplicar filtro
        
            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);
        
            app.gumes=length(app.indexgumes);
        
            %% Atualizar gráfico
        
            desenhagumesfiltro(app);           
        end

        % Value changed function: AlturahSlider
        function AlturahSliderValueChanged(app, event)
            limiteh=app.AlturahSlider.Value;

            %% Atualizar campo numérico

            app.alturahfield.Value=limiteh;

            %% Valores atuais dos outros filtros

            limitev=app.ParametrovSlider.Value;

            limiteangulo=app.LimiteangularSlider.Value;

            limitedistancia=Inf;

            %% Aplicar filtro

            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);

            app.gumes=length(app.indexgumes);

            %% Atualizar gráfico

            desenhagumesfiltro(app);            
        end

        % Value changing function: AlturahSlider
        function AlturahSliderValueChanging(app, event)
            limiteh=event.Value;

            %% Atualizar campo numérico

            app.alturahfield.Value=limiteh;

            %% Valores atuais dos outros filtros

            limitev=app.ParametrovSlider.Value;

            limiteangulo=app.LimiteangularSlider.Value;

            limitedistancia=Inf;

            %% Aplicar filtro

            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);

            app.gumes=length(app.indexgumes);

            %% Atualizar gráfico

            desenhagumesfiltro(app);            
        end

        % Value changed function: alturahfield
        function alturahfieldValueChanged(app, event)
            limiteh=app.alturahfield.Value;

            %% Atualizar slider

            app.AlturahSlider.Value=limiteh;

            %% Valores atuais dos outros filtros

            limitev=app.ParametrovSlider.Value;

            limiteangulo=app.LimiteangularSlider.Value;

            limitedistancia=Inf;

            %% Aplicar filtro

            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);

            app.gumes=length(app.indexgumes);

            %% Atualizar gráfico

            desenhagumesfiltro(app);            
        end

        % Value changing function: LimiteangularSlider
        function LimiteangularSliderValueChanging(app, event)
            limiteangulo=event.Value;
        
            %% Atualizar campo numérico
        
            app.limiteangularfield.Value=limiteangulo;
        
            %% Valores atuais dos outros filtros
        
            limiteh=app.AlturahSlider.Value;
        
            limitev=app.ParametrovSlider.Value;
        
            limitedistancia=Inf;
        
            %% Aplicar filtro
        
            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);
        
            app.gumes=length(app.indexgumes);
        
            %% Atualizar gráfico
        
            desenhagumesfiltro(app);            
        end

        % Value changed function: LimiteangularSlider
        function LimiteangularSliderValueChanged(app, event)
            limiteangulo=app.LimiteangularSlider.Value;

            %% Atualizar campo numérico

            app.limiteangularfield.Value=limiteangulo;

            %% Valores atuais dos outros filtros

            limiteh=app.AlturahSlider.Value;

            limitev=app.ParametrovSlider.Value;

            limitedistancia=Inf;

            %% Aplicar filtro

            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);

            app.gumes=length(app.indexgumes);

            %% Atualizar gráfico

            desenhagumesfiltro(app);            
        end

        % Value changed function: limiteangularfield
        function limiteangularfieldValueChanged(app, event)
            limiteangulo=app.limiteangularfield.Value;

            %% Atualizar slider

            app.LimiteangularSlider.Value=limiteangulo;

            %% Valores atuais dos outros filtros

            limiteh=app.AlturahSlider.Value;

            limitev=app.ParametrovSlider.Value;

            limitedistancia=Inf;

            %% Aplicar filtro

            app.indexgumes=aplicafiltrodinamico(app.indexgumesoriginal,app.geometriafiltro,limiteh,limitev,limiteangulo,limitedistancia);

            app.gumes=length(app.indexgumes);

            %% Atualizar gráfico

            desenhagumesfiltro(app);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {834, 834};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PosiodaAntenaPanel = uipanel(app.LeftPanel);
            app.PosiodaAntenaPanel.Title = 'Posição da Antena';
            app.AntenaTransmissoraButton = uibutton(app.PosiodaAntenaPanel, 'push');
            app.AntenaTransmissoraButton.ButtonPushedFcn = createCallbackFcn(app, @Transmissora, true);
            app.AntenaTransmissoraButton.Text = 'Antena Transmissora';
            app.AntenaReceptoraButton = uibutton(app.PosiodaAntenaPanel, 'push');
            app.AntenaReceptoraButton.ButtonPushedFcn = createCallbackFcn(app, @AntenaReceptoraButtonPushed, true);
            app.AntenaReceptoraButton.Text = 'Antena Receptora';
            app.AnlisesPanel = uipanel(app.LeftPanel);
            app.AnlisesPanel.Title = 'Análises';
            app.ElevaodoCaminhoButton = uibutton(app.AnlisesPanel, 'push');
            app.ElevaodoCaminhoButton.ButtonPushedFcn = createCallbackFcn(app, @ElevaodoCaminhoButtonPushed, true);
            app.ElevaodoCaminhoButton.Text = 'Elevação do Caminho';
            app.DifraoEpsteinPetersonButton = uibutton(app.AnlisesPanel, 'push');
            app.DifraoEpsteinPetersonButton.ButtonPushedFcn = createCallbackFcn(app, @DifraoEpsteinPetersonButtonPushed, true);
            app.DifraoEpsteinPetersonButton.Text = 'Difração Epstein-Peterson';
            app.ArquivosSalvosButton = uibutton(app.AnlisesPanel, 'push');
            app.ArquivosSalvosButton.ButtonPushedFcn = createCallbackFcn(app, @ArquivosSalvosButtonPushed, true);
            app.ArquivosSalvosButton.Text = 'Arquivos Salvos';
            app.AbriroutroenlaceparaanliseLabel = uilabel(app.AnlisesPanel);
            app.AbriroutroenlaceparaanliseLabel.Text = 'Abrir outro enlace para análise:';
            app.DifraoDeygoutButton = uibutton(app.AnlisesPanel, 'push');
            app.DifraoDeygoutButton.ButtonPushedFcn = createCallbackFcn(app, @DifraoDeygoutButtonPushed, true);
            app.DifraoDeygoutButton.Text = 'Difração Deygout';
            app.DifraoGiovaneliButton = uibutton(app.AnlisesPanel, 'push');
            app.DifraoGiovaneliButton.ButtonPushedFcn = createCallbackFcn(app, @DifraoGiovaneliButtonPushed, true);
            app.DifraoGiovaneliButton.Text = 'Difração Giovaneli';
            app.CaminhoPanel = uipanel(app.LeftPanel);
            app.CaminhoPanel.Title = 'Caminho';
            app.ElevaoEntreAntenasCheckBox = uicheckbox(app.CaminhoPanel);
            app.ElevaoEntreAntenasCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElevaoEntreAntenasCheckBoxValueChanged, true);
            app.ElevaoEntreAntenasCheckBox.Text = 'Elevação Entre Antenas';
            app.TraoLoSCheckBox = uicheckbox(app.CaminhoPanel);
            app.TraoLoSCheckBox.ValueChangedFcn = createCallbackFcn(app, @TraoLoSCheckBoxValueChanged, true);
            app.TraoLoSCheckBox.Text = 'Traço LoS';
            app.GumesdeFacaCheckBox = uicheckbox(app.CaminhoPanel);
            app.GumesdeFacaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GumesdeFacaCheckBoxValueChanged, true);
            app.GumesdeFacaCheckBox.Text = 'Gumes de Faca';
            app.AtualizarButton = uibutton(app.CaminhoPanel, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = 'Atualizar';
            app.ParmetrosPanel = uipanel(app.LeftPanel);
            app.ParmetrosPanel.Title = 'Parâmetros';
            app.DistnciaEntreAntenasEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.DistnciaEntreAntenasEditFieldLabel.Text = 'Distância Entre Antenas';
            app.DistnciaEntreAntenasEditField = uieditfield(app.ParmetrosPanel, 'numeric');
            app.DistnciaEntreAntenasEditField.ValueChangedFcn = createCallbackFcn(app, @DistnciaEntreAntenasEditFieldValueChanged, true);
            app.PerdasdeCaminhoEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.PerdasdeCaminhoEditFieldLabel.Text = 'Perdas de Caminho';
            app.PerdasdeCaminhoEditField = uieditfield(app.ParmetrosPanel, 'text');
            app.AlturadaTransmissoraEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.AlturadaTransmissoraEditFieldLabel.Text = 'Altura da Transmissora';
            app.AlturadaTransmissoraEditField = uieditfield(app.ParmetrosPanel, 'numeric');
            app.AlturadaTransmissoraEditField.Limits = [0 150];
            app.AlturadaTransmissoraEditField.ValueChangedFcn = createCallbackFcn(app, @AlturadaTransmissoraEditFieldValueChanged, true);
            app.AlturadaReceptoraEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.AlturadaReceptoraEditFieldLabel.Text = 'Altura da Receptora';
            app.AlturadaReceptoraEditField = uieditfield(app.ParmetrosPanel, 'numeric');
            app.AlturadaReceptoraEditField.Limits = [0 150];
            app.AlturadaReceptoraEditField.ValueChangedFcn = createCallbackFcn(app, @AlturadaReceptoraEditFieldValueChanged, true);
            app.GanhodaTransmissoraEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.GanhodaTransmissoraEditFieldLabel.Text = 'Ganho da Transmissora';
            app.FrequnciaMHzEditField = uieditfield(app.ParmetrosPanel, 'numeric');
            app.FrequnciaMHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequnciaMHzEditFieldValueChanged, true);
            app.GanhodaTransmissoraEditField = uieditfield(app.ParmetrosPanel, 'numeric');
            app.GanhodaTransmissoraEditField.ValueChangedFcn = createCallbackFcn(app, @GanhodaTransmissoraEditFieldValueChanged, true);
            app.GanhodaReceptoraEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.GanhodaReceptoraEditFieldLabel.Text = 'Ganho da Receptora';
            app.GanhodaReceptoraEditField = uieditfield(app.ParmetrosPanel, 'numeric');
            app.GanhodaReceptoraEditField.ValueChangedFcn = createCallbackFcn(app, @GanhodaReceptoraEditFieldValueChanged, true);
            app.FrequnciaMHzEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.FrequnciaMHzEditFieldLabel.Text = 'Frequência (MHz)';
            app.EditFieldLabel = uilabel(app.ParmetrosPanel);
            app.EditFieldLabel.Text = 'Perdas de Modelo';
            app.PerdasdeModeloEditField = uieditfield(app.ParmetrosPanel, 'text');
            app.CoordenadasPersonalizadasPanel = uipanel(app.LeftPanel);
            app.CoordenadasPersonalizadasPanel.Title = 'Coordenadas Personalizadas';
            app.LatTXEditFieldLabel = uilabel(app.CoordenadasPersonalizadasPanel);
            app.LatTXEditFieldLabel.Text = 'Lat TX';
            app.LatTXEditField = uieditfield(app.CoordenadasPersonalizadasPanel, 'numeric');
            app.LatTXEditField.Limits = [-90 90];
            app.LatTXEditField.ValueChangedFcn = createCallbackFcn(app, @LatTXEditFieldValueChanged, true);
            app.LonTXEditFieldLabel = uilabel(app.CoordenadasPersonalizadasPanel);
            app.LonTXEditFieldLabel.Text = 'Lon TX';
            app.LonTXEditField = uieditfield(app.CoordenadasPersonalizadasPanel, 'numeric');
            app.LonTXEditField.Limits = [-180 180];
            app.LonTXEditField.ValueChangedFcn = createCallbackFcn(app, @LonTXEditFieldValueChanged, true);
            app.LatRXEditFieldLabel = uilabel(app.CoordenadasPersonalizadasPanel);
            app.LatRXEditFieldLabel.Text = 'Lat RX';
            app.LatRXEditField = uieditfield(app.CoordenadasPersonalizadasPanel, 'numeric');
            app.LatRXEditField.Limits = [-90 90];
            app.LatRXEditField.ValueChangedFcn = createCallbackFcn(app, @LatRXEditFieldValueChanged, true);
            app.LonRXEditFieldLabel = uilabel(app.CoordenadasPersonalizadasPanel);
            app.LonRXEditFieldLabel.Text = 'Lon RX';
            app.LonRXEditField = uieditfield(app.CoordenadasPersonalizadasPanel, 'numeric');
            app.LonRXEditField.Limits = [-180 180];
            app.LonRXEditField.ValueChangedFcn = createCallbackFcn(app, @LonRXEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.MapaMundiTab = uitab(app.TabGroup);
            app.MapaMundiTab.Title = 'Mapa Mundi';
            app.FormatodoMapaDropDownLabel = uilabel(app.MapaMundiTab);
            app.FormatodoMapaDropDownLabel.Text = 'Formato do Mapa';
            app.FormatodoMapaDropDown = uidropdown(app.MapaMundiTab);
            app.FormatodoMapaDropDown.Items = {'streets-light', 'streets-dark', 'satellite', 'colorterrain'};
            app.FormatodoMapaDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatodoMapaDropDownValueChanged, true);
            app.FormatodoMapaDropDown.ClickedFcn = createCallbackFcn(app, @FormatodoMapaDropDownClicked, true);
            app.FormatodoMapaDropDown.Value = 'streets-light';
            app.CaminhoTab = uitab(app.TabGroup);
            app.CaminhoTab.Title = 'Caminho';
            app.CaminhoTab.ButtonDownFcn = createCallbackFcn(app, @CaminhoTabButtonDown, true);
            app.UIAxes = uiaxes(app.CaminhoTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Altura')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.FiltrodeGumesTab = uitab(app.TabGroup);
            app.FiltrodeGumesTab.Title = 'Filtro de Gumes';
            app.UIAxes_2 = uiaxes(app.FiltrodeGumesTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Altura')
            zlabel(app.UIAxes_2, 'Z')
            app.ParametrovSliderLabel = uilabel(app.FiltrodeGumesTab);
            app.ParametrovSliderLabel.Text = 'Parametro v';
            app.ParametrovSlider = uislider(app.FiltrodeGumesTab);
            app.ParametrovSlider.Limits = [0 0.5];
            app.ParametrovSlider.ValueChangedFcn = createCallbackFcn(app, @ParametrovSliderValueChanged, true);
            app.ParametrovSlider.ValueChangingFcn = createCallbackFcn(app, @ParametrovSliderValueChanging, true);
            app.AlturahSliderLabel = uilabel(app.FiltrodeGumesTab);
            app.AlturahSliderLabel.Text = 'Altura h';
            app.AlturahSlider = uislider(app.FiltrodeGumesTab);
            app.AlturahSlider.Limits = [0 2];
            app.AlturahSlider.ValueChangedFcn = createCallbackFcn(app, @AlturahSliderValueChanged, true);
            app.AlturahSlider.ValueChangingFcn = createCallbackFcn(app, @AlturahSliderValueChanging, true);
            app.LimiteangularSliderLabel = uilabel(app.FiltrodeGumesTab);
            app.LimiteangularSliderLabel.Text = 'Limite angular';
            app.LimiteangularSlider = uislider(app.FiltrodeGumesTab);
            app.LimiteangularSlider.Limits = [0 0.5];
            app.LimiteangularSlider.ValueChangedFcn = createCallbackFcn(app, @LimiteangularSliderValueChanged, true);
            app.LimiteangularSlider.ValueChangingFcn = createCallbackFcn(app, @LimiteangularSliderValueChanging, true);
            app.SalvarFiltroButton = uibutton(app.FiltrodeGumesTab, 'push');
            app.SalvarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarFiltroButtonPushed, true);
            app.SalvarFiltroButton.Text = 'Salvar Filtro';
            app.parametrovfield = uieditfield(app.FiltrodeGumesTab, 'numeric');
            app.parametrovfield.ValueChangedFcn = createCallbackFcn(app, @parametrovfieldValueChanged, true);
            app.alturahfield = uieditfield(app.FiltrodeGumesTab, 'numeric');
            app.alturahfield.ValueChangedFcn = createCallbackFcn(app, @alturahfieldValueChanged, true);
            app.limiteangularfield = uieditfield(app.FiltrodeGumesTab, 'numeric');
            app.limiteangularfield.ValueChangedFcn = createCallbackFcn(app, @limiteangularfieldValueChanged, true);
            app.FresnelAtivoCheckBox = uicheckbox(app.FiltrodeGumesTab);
            app.FresnelAtivoCheckBox.Text = 'Fresnel Ativo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathLoss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Mapa1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end